classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout19                    matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        V09BETARelaseLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerAmplifierTab               matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        PASettingsPanel                 matlab.ui.container.TabGroup
        InstrumentsTab                  matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        PADisconnectButton              matlab.ui.control.Button
        MeasurementDelaysEditFieldLabel  matlab.ui.control.Label
        SpectrumAnalyzerLabel           matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAMeasurementDelayValueField    matlab.ui.control.NumericEditField
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SignalGeneratorLabel            matlab.ui.control.Label
        PowerSupplyBLabel               matlab.ui.control.Label
        PowerSupplyALabel               matlab.ui.control.Label
        SpectrumAnalyzerStatusLabel     matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorStatusLabel      matlab.ui.control.Label
        PowerSupplyBDropDown            matlab.ui.control.DropDown
        PowerSupplyBStatusLabel         matlab.ui.control.Label
        PowerSupplyADropDown            matlab.ui.control.DropDown
        PowerSupplyAStatusLabel         matlab.ui.control.Label
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        SupplyChannelDropDownLabel      matlab.ui.control.Label
        SupplyModeLabel                 matlab.ui.control.Label
        SupplyVoltageStepValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStepNameField      matlab.ui.control.Label
        SupplyVoltageStopValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStopEditField      matlab.ui.control.Label
        SupplyVoltageStartValueField    matlab.ui.control.NumericEditField
        SupplyVoltageStartNameField     matlab.ui.control.Label
        LimitingCurrentALabel           matlab.ui.control.Label
        SupplyCurrentValueField         matlab.ui.control.NumericEditField
        SingleSweepSwitch               matlab.ui.control.Switch
        DrainGateSwitch                 matlab.ui.control.Switch
        PowerSupplyChannelDropDown      matlab.ui.control.DropDown
        PowerSupplyModeDropDown         matlab.ui.control.DropDown
        SignalAnalysisTab               matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        SpectrumAnalyzerSettingsLabel   matlab.ui.control.Label
        SignalGeneratorSettingsLabel    matlab.ui.control.Label
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        ReferenceLevelValueField        matlab.ui.control.NumericEditField
        ReferenceLevelNameField         matlab.ui.control.Label
        SweepPointsValueField           matlab.ui.control.NumericEditField
        SweepPointsNameField            matlab.ui.control.Label
        SpanValueField                  matlab.ui.control.NumericEditField
        SpanNameField                   matlab.ui.control.Label
        PowerStep                       matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPower                        matlab.ui.control.NumericEditField
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPower                      matlab.ui.control.NumericEditField
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStep                   matlab.ui.control.NumericEditField
        FrequencyStepMHzLabel           matlab.ui.control.Label
        EndFrequency                    matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel            matlab.ui.control.Label
        StartFrequency                  matlab.ui.control.NumericEditField
        StartFrequencyMHzLabel          matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        CalibrationTab                  matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        FixedCalibrationLabel           matlab.ui.control.Label
        DriverResponseLabel             matlab.ui.control.Label
        SmallSignalFrequencyResponseLabel  matlab.ui.control.Label
        OutputAttenuationdBEditFieldLabel  matlab.ui.control.Label
        LargeSignalDriverResponseFilenameField  matlab.ui.control.EditField
        LoadLargeSignalDriverResponseButton  matlab.ui.control.Button
        OutputSmallSignalFilenameField  matlab.ui.control.EditField
        InputAttenuationdBLabel         matlab.ui.control.Label
        CalibrationModeLabel            matlab.ui.control.Label
        LoadOuputSmallSignalButton      matlab.ui.control.Button
        InputSmallSignalFilenameField   matlab.ui.control.EditField
        LoadInputSmallSignalButton      matlab.ui.control.Button
        OutputAttenuationValueField     matlab.ui.control.NumericEditField
        InputAttenuationValueField      matlab.ui.control.NumericEditField
        CalibrationModeDropDown         matlab.ui.control.DropDown
        TestSettingsPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        PlotPAData                      matlab.ui.control.Button
        SavePAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        ResultsTabs                     matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        SingleFrequencyPAPlot           matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CompressionPointsPlot           matlab.ui.control.UIAxes
        PeakDEPAEPlot                   matlab.ui.control.UIAxes
        PeakGainPlot                    matlab.ui.control.UIAxes
        GainvsOutputPowerPlot           matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        TestandPlotSettingsPanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        StopTestButton                  matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        PlotAngleDropDown               matlab.ui.control.DropDown
        PlottingAngleLabel              matlab.ui.control.Label
        PlotFrequencyGHzDropDown        matlab.ui.control.DropDown
        PlotFrequencyGHzDropDownLabel   matlab.ui.control.Label
        AntennaSettings                 matlab.ui.container.TabGroup
        InstrumentsTab_2                matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        AntennaMeasurementDelayValueField  matlab.ui.control.NumericEditField
        MeasurementDelaysLabel          matlab.ui.control.Label
        AntennaDisconnectButton         matlab.ui.control.Button
        AntennaConnectButton            matlab.ui.control.Button
        EMCenterStatusLabel             matlab.ui.control.Label
        EMCenterLabel                   matlab.ui.control.Label
        EMCenterDropDown                matlab.ui.control.DropDown
        VNADropDown                     matlab.ui.control.DropDown
        VNAStatusLabel                  matlab.ui.control.Label
        VNALabel                        matlab.ui.control.Label
        VNATab                          matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        SweepPointsLabel                matlab.ui.control.Label
        VNASweepPoints                  matlab.ui.control.NumericEditField
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAMeasurement                  matlab.ui.control.DropDown
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        VNAStartFrequency               matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        TableSpeedSlider                matlab.ui.control.Slider
        TableSpeedSliderLabel           matlab.ui.control.Label
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        MovetoAngleButton               matlab.ui.control.Button
        TableNewAngle                   matlab.ui.control.NumericEditField
        StopTableButton                 matlab.ui.control.Button
        TowerTab                        matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        TowerSpeedSlider                matlab.ui.control.Slider
        TowerSpeedSliderLabel           matlab.ui.control.Label
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        MoveTowertoAngleButton          matlab.ui.control.Button
        TowerNewAngle                   matlab.ui.control.NumericEditField
        StopTowerButton                 matlab.ui.control.Button
        LinearSliderTab                 matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        AntennaPhysicalSize             matlab.ui.control.NumericEditField
        AntennaOffsetLabel              matlab.ui.control.Label
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        SliderPositioncmLabel           matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingLabel             matlab.ui.control.Label
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        StopLinearSliderButton          matlab.ui.control.Button
        AntennaPattern                  matlab.ui.container.TabGroup
        AntennaGainTab                  matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        BrowseReferenceFileButton       matlab.ui.control.Button
        ReferenceGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        RadiationPatternTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Specific Settings
        StoredAddresses = {};
        LINEAR_SLIDER_RANGE = 2    % Maximum linear slider movement value (2 meters)
        EXCEL_MAX_ROWS = 1048576;  % Maximum number of rows in Excel
        EXCEL_MAX_COLUMNS = 16384; % Maximum number of columns in Excel
        StopRequested = false;     % Boolean flag: true if user requested a measurement to stop, false otherwise.
        RadiationPlot2D

        %% Antenna Properties
        % Plot Properties
        azimuthAngles              % Measured azimuth angles (deg)
        elevationAngles            % Measured elevation anges (deg)
        sweepFrequencies           % Measured VNA sweep frequencies (Hz)
        antennaRL                  % Measured antenna return loss (dB)
        antennaGain                % Measured antenna gain (dBi)
        selectedAngle              % User selected angle to plot
        selectedFrequency          % User selected frequency to plot 
        angleRange                 % 
        frequencyRange             % 

        % Measurement Properties
        ReferenceGainFile          %
        AzimuthAngles              %
        TestFrequencies            %
        S11                        % 
        S21                        %
        S22                        %
        AntennaGain_dBi            %
        offsetSpacing = 0.8062;    % Spacing between antennas (m)
        linearSpacing              % Spacing added by linear slider (m)
        setupSpacing               % Total spacing between antennas accounting for LS and offset (m)
    end
    
    properties (Access = public)
        %% Instrument Objects
        PowerSupplyA               % DC Power Supply A 
        PowerSupplyB               % DC Power Supply B
        SignalGenerator            % Signal Generator
        SpectrumAnalyzer           % Spectrum Analyzer
        VNA                        % Vector Network Analyzer
        EMCenter                   % Anechoic Chamber Control  
        LinearSlider               % Anechoic Chamber Linear Slider

        %% Power Amplifier Properties
        ChannelToDeviceMap         % A containers.Map linking logical channel names (e.g., 'A1') to PSU device channels (e.g., 'CH1,PSUA').
        PSUMode                    % Single, Dual, or Quad Supply Mode
        CalibrationMode            % None, Fixed Attenuation, Small Signal, Small + Large Signal 
        TypeFreqMeasurementMode    % Single frequency or sweep frequencies
        ChannelNames               % Struct containing the names of the channels to be used by the PSU.
        FilledPSUChannels          % Cell array containing the names of the configured PSU channels that are ready for use.
        DrainChannels              % Cell array containing the channels designated as drains.
        GateChannels               % Cell array containing the channels designated as gates.
        InputSpFile                % Path for the S2P file for input network de-embedding.
        OutputSpFile               % Path for the S2P file for output network de-embedding.
        DriverFile                 % Path for the driver configuration file containing a power amplifier characterization.
        PAMeasurementsTable        % Table storing the results of various measurements for the power amplifier.

        % Measurement Properties
        PA_Frequencies
        PA_Voltages 
        PA_RFInputPower 
        PA_RFOutputPower 
        PA_Gain
        PA_DE 
        PA_PAE 
        PA_Voltages1
        PA_Voltages2
    end
   
    
    methods (Access = private)

        function connectInstrument(app, instrumentNames, instrumentResources)
            % This function establishes a connection to a set of instruments using their VISA resource names. It iterates through 
            % the provided instrument names and resources, checks if the instrument is enabled for connection, and attempts to connect 
            % using MATLAB’s visadev function. Upon successful connection, the function updates the UI with the connection status and 
            % device information; if the connection fails, it displays an error message.
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                statusLabelField = strcat(instrumentName, 'StatusLabel'); 

                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource) && ~strcmp(instrumentResource, 'NA')
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            app.(instrumentName).ByteOrder = 'little-endian';
                            app.(dropdownFieldName).Enable = 'off';
                            idn = writeread(app.(instrumentName), '*IDN?');

                            % Update status label to show success.
                            if strcmp(instrumentName, 'EMCenter')
                                idnParts = split(idn, ' ');
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{3});
                            else
                                idnParts = split(idn, ",");  
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{2});
                            end
                        catch ME
                            % Update status label to show failure.
                            app.(statusLabelField).Text = "Failed";
                            app.displayError(ME);
                        end
                    else
                        % Update status label if "NA: NA" is selected.
                        app.(statusLabelField).Text = "Not Connected";
                    end
                end
            end
        end
                                

        function peakValues = measureRFParametersPeaks(app)
            % This function calculates the peak values of several RF 
            % parameters (Gain, DE, PAE, Saturation Power, -1dB and -3dB 
            % compression points).
            numFreqs = length(app.PA_Frequencies);
            peakGain = zeros(numFreqs, 1);
            peak1dB  = zeros(numFreqs, 1);
            peak3dB  = zeros(numFreqs, 1);
        
            for i = 1:numFreqs
                % Get the peak Gain.
                peakGain(i) = max(app.PA_Gain(i, :));

                % Get the -1dB compression point.
                idx_1dB = find(app.PA_Gain(i, :) <= (peakGain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak1dB(i) = app.PA_RFOutputPower(i, idx_1dB);
                else
                    peak1dB(i) = NaN;
                end

                % Get the -3dB compression point.
                idx_3dB = find(app.PA_Gain(i, :) <= (peakGain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak3dB(i) = app.PA_RFOutputPower(i, idx_3dB);
                else
                    peak3dB(i) = NaN;
                end
            end    

            % Get the max output RF power per frequency.
            saturationPower = max(app.PA_RFOutputPower, [], 2);
            % Get the max drain efficiency per frequency.
            peakDE  = max(app.PA_DE, [], 2);
            % Get the max power-added efficiency per frequency.
            peakPAE = max(app.PA_PAE, [], 2);
     
            peakValues = {saturationPower, peakGain, peakDE, peakPAE, peak1dB', peak3dB'};
        end

        function plotPASingleMeasurement(app)
            % This function plots the data from the single frequency
            % measurement.
            cla(app.SingleFrequencyPAPlot);
            
            peakValues = app.measureRFParametersPeaks();
            %peakValues{1} is saturation power
            %peakValues{5} is the -1db compression point
            %peakValues{6} is the -3db compression point

            % Plot Gain on the left y-axis
            yyaxis(app.SingleFrequencyPAPlot, 'left');
            h1 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, app.PA_Gain, 'k-');
            hold(app.SingleFrequencyPAPlot, 'on');
            grid(app.SingleFrequencyPAPlot, 'on');
            ylabel(app.SingleFrequencyPAPlot, 'Gain (dB)', 'FontWeight', 'bold');
            %set(app.SingleFrequencyPAPlot, 'YColor', 'k'); changes color of y axis

            % Plot DE and PAE on the right y-axis.
            yyaxis(app.SingleFrequencyPAPlot, 'right');
            h2 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, app.PA_DE, 'b-');
            h3 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, app.PA_PAE, 'r--');

            ylabel(app.SingleFrequencyPAPlot, 'Efficiency (%)', 'FontWeight', 'bold');
            %set(app.SingleFrequencyPAPlot, 'YColor', [0.3, 0.3, 0.3]);

            hold(app.SingleFrequencyPAPlot, 'off');
            xlabel(app.SingleFrequencyPAPlot, 'Output Power (dBm)', 'FontWeight', 'bold');
            title(app.SingleFrequencyPAPlot, 'PA Performance Metrics', 'FontWeight', 'bold');

            legendEntries = {'Gain', 'DE', 'PAE'};
            legendHandles = [h1, h2, h3];

            % Plot the compresssion points.
            if ~isnan(peakValues{1}) % saturation
                h4 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, interp1(app.PA_RFOutputPower, app.PA_Gain, peakValues{1}), ...
                  'go', 'MarkerSize', 8, 'LineWidth', 2); % Green circle marker
                legendHandles(end+1) = h4;
                legendEntries{end+1} = 'P_{sat}';
            end
            if ~isnan(peakValues{5}) % -1db compression
                h5 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, interp1(app.PA_RFOutputPower, app.PA_Gain, peakValues{5}), ...
                  'mo', 'MarkerSize', 8, 'LineWidth', 2); % Magenta circle marker
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'P_{1dB}';
            end
            if ~isnan(peakValues{6}) % -3db compression
                h6 = plot(app.SingleFrequencyPAPlot, app.PA_RFOutputPower, interp1(app.PA_RFOutputPower, app.PA_Gain, peakValues{6}), ...
                  'co', 'MarkerSize', 8, 'LineWidth', 2); % Cyan circle marker
                legendHandles(end+1) = h6; 
                legendEntries{end+1} = 'P_{3dB}';
            end

            if numel(legendHandles) == numel(legendEntries)
                lgd = legend(app.SingleFrequencyPAPlot, legendHandles, legendEntries, 'Location', 'best');
                lgd.Box = 'on';
                lgd.FontSize = 9;
            else
                error('Mismatch between legend handles and entries.');
            end

            return;

            % Plot the compression points
            % if ~isempty(idx_1dB)
            %     h4 = plot(app.SingleFrequencyPAPlot, app.RFOutputPdBm(idx_1dB), app.PAGain(idx_1dB), ...
            %         'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'blue', 'MarkerFaceColor', 'blue');
            % end
            % if ~isempty(idx_3dB)
            %     h5 = plot(app.SingleFrequencyPAPlot, app.RFOutputPdBm(idx_3dB), app.PAGain(idx_3dB), ...
            %         'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red'); 
            % end
            % if ~isempty(saturation_idx)
            %     h6 = plot(app.SingleFrequencyPAPlot, saturation_power, app.PAGain(saturation_idx), ...
            %         'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'green', 'MarkerFaceColor', 'green');
            % end
        end

        function plotPASweepMeasurement(app)
            % This function plots the data from the frequency sweep
            % measurement.

            % Clear existing plots.
            cla(app.GainvsOutputPowerPlot);
            cla(app.PeakGainPlot);
            cla(app.PeakDEPAEPlot);
            cla(app.CompressionPointsPlot);

            % Nnumber of frequencies.
            numFreqs = length(app.PA_Frequencies);

            % Get peak values from RF measurements.
            peakValues = app.measureRFParametersPeaks();          
            
            % 1) Gain vs. Output RF Power
            hold(app.GainvsOutputPowerPlot, 'on'); 
            for i = 1:numFreqs
                plot(app.GainvsOutputPowerPlot, app.PA_RFOutputPower(i, :), app.PA_Gain(i, :));
            end
            hold(app.GainvsOutputPowerPlot, 'off');
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power');
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)');
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)');
            
            % 2) Peak Gain vs. Frequency
            plot(app.PeakGainPlot, app.PA_Frequencies / 1E9, peakValues{2}, 'b-o');
            title(app.PeakGainPlot, 'Peak Gain');
            xlabel(app.PeakGainPlot, 'Frequency (GHz)');
            ylabel(app.PeakGainPlot, 'Gain (dB)');
            
            % 3) Peak DE & PAE vs. Frequency
            hold(app.PeakDEPAEPlot, 'on');
            h1 = plot(app.PeakDEPAEPlot, app.PA_Frequencies / 1E9, peakValues{3}, 'b-o');
            h2 = plot(app.PeakDEPAEPlot, app.PA_Frequencies / 1E9, peakValues{4}, 'r-o');
            hold(app.PeakDEPAEPlot, 'off');
            title(app.PeakDEPAEPlot, 'Peak DE and PAE');
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)');
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)');
            legend(app.PeakDEPAEPlot, [h1, h2], {'DE', 'PAE'}, 'Location', 'best');

            % 4) Saturation Power & Compression Points
            hold(app.CompressionPointsPlot, 'on');
            plotColors = {'k', 'r', 'b'};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            plotHandles = [];
            usedLabels = {};
            
            % Plot each metric if it has valid data.
            % Saturation Power
            validIdx = ~isnan(peakValues{1});
            if any(validIdx)
                h1 = plot(app.CompressionPointsPlot, app.PA_Frequencies(validIdx) / 1E9, peakValues{1}(validIdx), [plotColors{1}, '-o']);
                plotHandles = [plotHandles, h1];
                usedLabels{end+1} = legendLabels{1};
            end
            
            % -1dB Compression Point
            validIdx = ~isnan(peakValues{5});
            if any(validIdx)
                h2 = plot(app.CompressionPointsPlot, app.PA_Frequencies(validIdx) / 1E9, peakValues{5}(validIdx), [plotColors{2}, '-o']);
                plotHandles = [plotHandles, h2];
                usedLabels{end+1} = legendLabels{2};
            end
            
            % -3dB Compression Point
            validIdx = ~isnan(peakValues{6});
            if any(validIdx)
                h3 = plot(app.CompressionPointsPlot, app.PA_Frequencies(validIdx) / 1E9, peakValues{6}(validIdx), [plotColors{3}, '-o']);
                plotHandles = [plotHandles, h3];
                usedLabels{end+1} = legendLabels{3};
            end  

            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points');
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)');
            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)');
            hold(app.CompressionPointsPlot, 'off');
            if ~isempty(plotHandles)
                legend(app.CompressionPointsPlot, plotHandles, usedLabels, 'Location', 'best');
            end
           
            % Improves the appearance of each plot, can adjust the line
            % thickness/width as desired.
            improveAxesAppearance(app.GainvsOutputPowerPlot, 'YYAxis', 'false', 'LineThickness', 1);
            improveAxesAppearance(app.PeakGainPlot, 'YYAxis', 'false', 'LineThickness', 1.5);
            improveAxesAppearance(app.PeakDEPAEPlot, 'YYAxis', 'false', 'LineThickness', 1.5);
            improveAxesAppearance(app.CompressionPointsPlot, 'YYAxis', 'false', 'LineThickness', 1.5);
        end

        function getBoresightGain(app, startFrequency, endFrequency, sweepPoints)
            try
                [SParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints);
                BoresightGain_dBi = measureAntennaGain(VNAFreqs, SParameters{2}, app.setupSpacing);
                combinedData = [double(VNAFreqs)', double(BoresightGain_dBi)', double(SParameters{1})'];
                combinedNames = {'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                saveData(combinedData, combinedNames, false);
            catch ME
                app.displayError(ME);
            end
        end

        function get2DAntennaGain(app, startFrequency, endFrequency, sweepPoints) 
            app.StopRequested = false;

            if ~isempty(app.ReferenceGainFile)
                ReferenceFreqs = app.ReferenceGainFile.FrequencyHz;
                ReferenceGain = app.ReferenceGainFile.GaindBi;
            end

            if isempty(app.TableSpeedSlider.Value)
                error('Table Speed cannot be empty.');
            elseif isempty(app.TableStartAngle.Value)
                error('Table Start Angle cannot be empty.');
            elseif isempty(app.TableStepAngle.Value)
                error('Table Step Angle cannot be empty.');
            elseif isempty(app.TableEndAngle.Value)
                error('Table End Angle cannot be empty.');
            else
                tableSpeed = app.TableSpeedSlider.Value;
                tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
                arraySize = sweepPoints * length(tableAngles);
            end
            
            app.S11 = zeros(1, arraySize);
            app.S21 = zeros(1, arraySize);
            app.S22 = zeros(1, arraySize); 
            app.AzimuthAngles = zeros(1, arraySize);
            app.TestFrequencies = zeros(1, arraySize);
            app.AntennaGain_dBi = zeros(1, arraySize);

            % Prepare variable names and types for the results table.
            varNames = {'Azimuth Angles (deg)',...
                        'Frequency (Hz)',...
                        'Gain (dBi)',...
                        'Return Loss (dB)'};
            varTypes = repmat({'double'}, 1, length(varNames));

            % Create results table
            ResultsTable = table('Size', [totalMeasurements, length(varNames)], ...
                                 'VariableTypes', varTypes, ...
                                 'VariableNames', varNames);
      
            try
                % Set speed of the turntable
                writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));

                for i = 1:length(tableAngles)
                    dataPts = (i-1)*sweepPoints + (1:sweepPoints);
                    adjustedAzimuthAngle = mod(tableAngles(i), 360);

                    % Move table to specified angle
                    writeline(app.EMCenter, sprintf('1A:SK %d', adjustedAzimuthAngle));
                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                    while status ~= 1
                        drawnow;

                        % Check if the user requested the test measurement to stop
                        if app.StopRequested
                            writeline(app.EMCenter, '1A:ST');
                            pause(1);
                            break;
                        end
                        
                        status = writeread(app.EMCenter,"1A:*OPC?");
                        status = str2double(strtrim(status));
                    end

                    % Exit the outer loop as well if stop was requested
                    if app.StopRequested
                        break;
                    end

                    % Small delay.
                    pause(app.AntennaMeasurementDelayValueField.Value / 2);

                    % Get S-Parameters and Frequencies from VNA
                    [SParameters, VNAFrequencies] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints); 

                    if ~isempty(app.ReferenceGainFile)
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing, ReferenceGain, ReferenceFreqs);
                    else
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing);
                    end

                    app.AzimuthAngles(dataPts) = tableAngles(i);
                    app.TestFrequencies(dataPts) = VNAFrequencies;
                    app.S11(dataPts) = SParameters{1};
                    app.S21(dataPts) = SParameters{2};
                    app.S22(dataPts) = SParameters{3};
                    app.AntennaGain_dBi(dataPts) = Gain_dBi;
                end

                % Return turntable to starting position
                writeline(app.EMCenter, sprintf('1A:SK %d', 0));

                % If the measurement was not stopped 
                if ~app.StopRequested
                    combinedData = [double(app.AzimuthAngles)', double(app.TestFrequencies)', double(app.AntennaGain_dBi)', double(app.S11)'];
                    combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
            
                    % Check if the dataset exceeds the Excel row limit
                    passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
            
                    % Save the measurement data
                    saveData(combinedData, combinedNames, passedLimit);
                end
            catch ME
                app.displayError(ME);
            end
        end

        function resetAntennaMeasurementProperties(app)
            app.AzimuthAngles = [];
            app.TestFrequencies = [];
            app.S11 = [];
            app.S21 = [];
            app.S22 = [];
            app.AntennaGain_dBi = [];
        end

        function plotAntenna2DMeasurement(app, sweepPoints)            
            cla(app.GainvsFrequency2D);
            cla(app.ReturnLoss2D);
            cla(app.GainvsAngle2D);
            cla(app.RadiationPlot2D);

            % Specified Angle Plotting  
            angleIndices = (app.angleRange*sweepPoints+1):((app.angleRange+1)*sweepPoints);
            angleGain = app.antennaGain(angleIndices);
            angleReturnLoss = app.antennaRL(angleIndices);

            % Specified Frequency Plotting
            frequencyIndices = app.frequencyRange:sweepPoints:length(app.antennaGain);
            frequencyGain = app.antennaGain(frequencyIndices);

            %disp(size(angleGain))
            %disp(size(app.AzimuthAngles))
            %disp(size(frequencyGain))

            % Antenna Gain vs. Frequency, at specified angle
            plot(app.GainvsFrequency2D, app.sweepFrequencies/1E9, angleGain);
            title(app.GainvsFrequency2D, sprintf('Gain vs. Frequency at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)');
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)');
            app.setAxisLimits(app.GainvsFrequency2D, angleGain);

            % Antenna Gains vs. Angles, at specified frequency.
            plot(app.GainvsAngle2D, app.azimuthAngles, frequencyGain);
            title(app.GainvsAngle2D, 'Gain vs. Angle');
            xlabel(app.GainvsAngle2D, 'Angle (degrees)');
            ylabel(app.GainvsAngle2D, 'Gain (dBi)');
            xlim(app.GainvsAngle2D, [-180 180]);
            app.setAxisLimits(app.GainvsAngle2D, frequencyGain);

            % Return Loss (dB) Plot
            plot(app.ReturnLoss2D, app.sweepFrequencies/1E9, angleReturnLoss);
            title(app.ReturnLoss2D, sprintf('Return Loss at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)');
            ylabel(app.ReturnLoss2D, 'RL (dB)');

            % 2D Radiation Pattern Plot
            polarplot(app.RadiationPlot2D, deg2rad(app.azimuthAngles), frequencyGain);
            title(app.RadiationPlot2D, sprintf('Radiation Pattern at %1.3f GHz', str2double(app.selectedFrequency)));
            thetalim(app.RadiationPlot2D, [0 360]);
            app.setAxisLimits(app.RadiationPlot2D, frequencyGain);

            improveAxesAppearance(app.GainvsFrequency2D, 'LineThickness', 2);
            improveAxesAppearance(app.ReturnLoss2D, 'LineThickness', 2);
            improveAxesAppearance(app.GainvsAngle2D, 'LineThickness', 2);
            %improveAxesAppearance(app.RadiationPlot2D);
        end

        
        %% HELPER FUNCTIONS
        function saveInstrumentAddressestoTextFile(~, address, model)
            % Save to the text file containing the lab instruments and 
            % their respective IP addresses.
            fid = fopen('instrument_address_history.txt', 'a');
            
            % If the user provided the instrument model, we save it along 
            % with the IP address.
            if nargin > 2 && ~isempty(model)
                fprintf(fid, '%% Model: %s\n', model);
            end
            
            % Save the IP address.
            fprintf(fid, '%% Address: %s\n', address);
            fclose(fid);
        end


        function loadInstrumentAddressestoTextFile(app)
            [appPath, ~, ~] = fileparts(mfilename('fullpath'));
            filename = fullfile(appPath, 'instrument_address_history.txt');

            % Load from the text file containing the lab instruments and 
            % their respective IP addresses.
            if isfile(filename)
                fileContent = fileread(filename);
                lines = strsplit(fileContent, '\n');
 
                addresses = {};
                displayNames = {};
                currentModel = '';
                
                for i = 1:length(lines)
                    line = strtrim(lines{i});
                    
                    if isempty(line)
                        continue;
                    end
                    
                    % Check if this is the line containing the instrument 
                    % model.
                    if startsWith(line, '% Model:')
                        currentModel = strtrim(extractAfter(line, '% Model:'));
                    % Check if this is the line containing the instrument
                    % address.
                    elseif startsWith(line, '% Address:')
                        address = strtrim(extractAfter(line, '% Address:'));
                        addresses{end+1} = address;
                        
                        % Create display name with model and address. 
                        % Model: SMW200A TCPIP0::192.168.3.52::hislip0::INSTR
                        if ~isempty(currentModel)
                            displayNames{end+1} = sprintf('%s: %s', currentModel, address);
                        else
                            displayNames{end+1} = address;
                        end
                        
                        currentModel = '';
                    end
                end

                % Update the app's instrument dropdowns.
                Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown};
                for idx = 1:numel(Dropdowns)
                    currentDropdown = Dropdowns{idx};
                    currentDropdown.Items = displayNames;
                end
            else
                app.StoredAddresses = {};
            end
        end


        function setAxisLimits(~, axisHandle, data)
            minData = min(data);
            maxData = max(data);
            margin = (maxData - minData) * 0.1;

            if isa(axisHandle, 'matlab.ui.control.UIAxes')
                if margin == 0
                    margin = 0.1;
                end
                ylim(axisHandle, [minData - margin, maxData + margin]);              
            elseif isa(axisHandle, 'matlab.graphics.axis.PolarAxes')
                if margin == 0
                    margin = 1; 
                end
                rlim(axisHandle, [minData - margin, maxData + margin]);
            end
        end

        
        function displayError(app, ME)
            % This function displays a detailed error message to the GUI 
            % alert box. It takes in an MException object (ME), extracts
            % the error details (message, type, and stack trace), 
            % and formats them into a readable string for the user.
            detailedErrorMessage = sprintf('Error: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);
            for k = 1:length(ME.stack)
                detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                    detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
            end
            uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
        end

        function prepareResultsForPlotting(app)
            % This function processes the measurement results table to prepare the data
            % for plotting with plotPASweepMeasurement
            
            % Check if results table exists
            if isempty(app.PAMeasurementsTable)
                warning('No measurement results available.');
                return;
            end
            
            % Extract data from the results table
            resultsTable = app.PAMeasurementsTable;
            
            % Get unique frequencies (in Hz)
            app.PA_Frequencies = unique(resultsTable.("Frequency (MHz)")) * 1E6;
            numFreqs = length(app.PA_Frequencies);
            
            % Get unique input power levels
            inputPowers = unique(resultsTable.("RF Input Power (dBm)"));
            numPowers = length(inputPowers);
            
            % Initialize arrays to store data for each frequency and power
            app.PA_RFOutputPower = zeros(numFreqs, numPowers);
            app.PA_Gain = zeros(numFreqs, numPowers);
            app.PA_DE = zeros(numFreqs, numPowers);
            app.PA_PAE = zeros(numFreqs, numPowers);
            
            % Populate the arrays
            for i = 1:numFreqs
                % Get rows for this frequency
                freqRows = resultsTable.("Frequency (MHz)") == app.PA_Frequencies(i)/1E6;
                
                % For each power level
                for j = 1:numPowers
                    % Get row for this frequency and power
                    idx = freqRows & resultsTable.("RF Input Power (dBm)") == inputPowers(j);
                    
                    if any(idx)
                        % In case of multiple matches (e.g., voltage sweep), take the first one or average
                        app.PA_RFOutputPower(i, j) = mean(resultsTable.("RF Output Power (dBm)")(idx));
                        app.PA_Gain(i, j) = mean(resultsTable.("Gain")(idx));
                        app.PA_DE(i, j) = mean(resultsTable.("DE (%)")(idx));
                        app.PA_PAE(i, j) = mean(resultsTable.("PAE (%)")(idx));
                    end
                end
            end
            
            % Sort data by input power for each frequency
            for i = 1:numFreqs
                % Get rows for this frequency
                freqRows = resultsTable.("Frequency (MHz)") == app.PA_Frequencies(i)/1E6;
                freqData = resultsTable(freqRows, :);
                
                % Sort by input power
                [~, sortIdx] = sort(freqData.("RF Input Power (dBm)"));
                sortedData = freqData(sortIdx, :);
                
                % Store in the app properties
                app.PA_RFOutputPower(i, :) = sortedData.("RF Output Power (dBm)")';
                app.PA_Gain(i, :) = sortedData.("Gain")';
                app.PA_DE(i, :) = sortedData.("DE (%)")';
                app.PA_PAE(i, :) = sortedData.("PAE (%)")';
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application startup settings.
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            app.loadInstrumentAddressestoTextFile();
            
            % Add all relevant Application support functions to the path.
            addpath(genpath('.\'))
            
            % Handle PA specific settings.
            app.PSUMode = 'Single Supply';
            PAChangeCalibrationMode(app);
            PAChangeFrequencyMeasurementMode(app);
            app.ChannelNames = struct();
            app.ChannelToDeviceMap = containers.Map({'A1', 'A2', 'B1', 'B2'}, {'CH1,PSUA', 'CH2,PSUA', 'CH1,PSUB', 'CH2,PSUB'});
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField.Visible = 'on';
            app.SupplyVoltageStartValueField.Visible = 'on';
            app.SupplyVoltageStopValueField.Visible = 'off';
            app.SupplyVoltageStepValueField.Visible = 'off';

            % Handle Antenna specific settings.
            app.RadiationPlot2D = polaraxes(app.GridLayout3);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';
            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';
        end

        % Value changed function: PowerSupplyADropDown, 
        % ...and 3 other components
        function PAInstrumentsAddressChanged(app, event)
            % When a new address is entered into the instrument address 
            % dropdown menu, this callback:
            % 1. Adds the address to the stored addresses list.
            % 2. Updates all instrument menus with the new address.
            % 3. Saves the updated address list to a text file outside the application.
        
            source = event.Source;
            Input = source.Value;

            pattern = '^([A-Za-z0-9\-]+):\s*(TCPIP0::[\d\.]+::[\w\-]+::[\w\-]+)$';
    
            % Check if the input matches the pattern
            if ~isempty(regexp(Input, pattern, 'once')) || strcmp(Input, 'NA: NA')
                return;
            end
            
            % Validate the input starts with "Model:"
            if ~startsWith(Input, 'Model:', 'IgnoreCase', true)
                errordlg('Invalid format. The input must start with "Model:".', 'Invalid Address');
                return;
            end
            
            pattern = '^Model:\s+(\S+)\s+(.*)';
            tokens = regexp(Input, pattern, 'tokens');
            
            % Check if the regex found a match
            if isempty(tokens)
                errordlg('Invalid input format. Ensure the format is "Model: <model> <address>".', 'Invalid Address');
                return;
            end
            
            % Extract model and address from regex tokens
            model = tokens{1}{1};
            address = strtrim(tokens{1}{2});
            
            % Check if model and address are valid (non-empty)
            if isempty(model)
                errordlg('No model specified after "Model:".', 'Invalid Address');
                return;
            end
            if isempty(address)
                errordlg('No address specified after the model.', 'Invalid Address');
                return;
            end
            
            if ~ismember(address, app.StoredAddresses)
                app.StoredAddresses{end+1} = address;
                
                % Update the app's instrument dropdowns.
                Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown};
                for idx = 1:numel(Dropdowns)
                    currentDropdown = Dropdowns{idx};
                    if ~ismember(address, currentDropdown.Items)
                        currentDropdown.Items{end+1} = address;
                    end
                end
                
                % Save the new address and model to the text file.
                saveInstrumentAddressestoTextFile(app, address, model);
            end    
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyADropDown.Value,app.PowerSupplyBDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            
            pattern = '(TCP|USB)[^ ]*';
            
            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstrument(instrumentNames, instrumentResources);
        end

        % Value changed function: PowerSupplyModeDropDown
        function PSUChangeMode(app, event)
            newMode = app.PowerSupplyModeDropDown.Value; 

            % The default value of app.PSUMode is 'Single Supply' for
            % safety reasons.
            if isempty(newMode)
                app.PSUMode = 'Single Supply';
            else
                app.PSUMode = newMode;
            end

            % If the mode changes to 'No Supply' all input parameters to
            % the PSU are disabled to the user.
            switch newMode
                case 'No Supply'
                    app.SupplyCurrentValueField.Enable = 'off';
                    app.SupplyVoltageStartValueField.Enable = 'off';
                    app.SupplyVoltageStopValueField.Enable = 'off';
                    app.SupplyVoltageStepValueField.Enable = 'off';
                otherwise
                    app.SupplyCurrentValueField.Enable = 'on';
                    app.SupplyVoltageStartValueField.Enable = 'on';
                    app.SupplyVoltageStopValueField.Enable = 'on';
                    app.SupplyVoltageStepValueField.Enable = 'on';
            end

            app.PSUChannelDropDownOpening();
        end

        % Value changed function: PowerSupplyChannelDropDown
        function PSUChangeChannel(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel) || ~ischar(selectedChannel)
                return;
            end

            % If the channel is new, initialize its values
            if ~isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel) = struct('Current', 0, 'Start', 0, 'Stop', 0, 'Step', 0, 'Mode', 'Single');

                % New channel is drain by default
                if ~ismember(selectedChannel, [app.DrainChannels, app.GateChannels])
                    app.DrainChannels{end+1} = selectedChannel;
                    app.DrainGateSwitch.Value = 'Drain';
                end
            else
                % Set the mode switch based on saved channel mode
                if isfield(app.ChannelNames.(selectedChannel), 'Mode')
                    app.SingleSweepSwitch.Value = app.ChannelNames.(selectedChannel).Mode;
                else
                    app.SingleSweepSwitch.Value = 'Single';  % Default to single if mode not set
                    app.ChannelNames.(selectedChannel).Mode = 'Single';
                end

                % Update DrainGateSwitch based on channel designation
                if any(strcmp(selectedChannel, app.DrainChannels))
                    app.DrainGateSwitch.Value = 'Drain';
                elseif any(strcmp(selectedChannel, app.GateChannels))
                    app.DrainGateSwitch.Value = 'Gate';
                else
                    % Default to Drain if not in either list
                    app.DrainGateSwitch.Value = 'Drain';
                    app.DrainChannels{end+1} = selectedChannel;
                end

                % Call the SingleSweepSwitch function to update UI visibility
                PSUChangeSingleSweepVoltages(app, []);
            end

            % Load saved values into UI edit fields
            app.SupplyCurrentValueField.Value = app.ChannelNames.(selectedChannel).Current;
            app.SupplyVoltageStartValueField.Value = app.ChannelNames.(selectedChannel).Start;
            app.SupplyVoltageStopValueField.Value = app.ChannelNames.(selectedChannel).Stop;
            app.SupplyVoltageStepValueField.Value = app.ChannelNames.(selectedChannel).Step;
        end

        % Drop down opening function: PowerSupplyChannelDropDown
        function PSUChannelDropDownOpening(app, event)
            % Check which PSUs are connected
            hasPSUA = ~isempty(app.PowerSupplyA);
            hasPSUB = ~isempty(app.PowerSupplyB);

            if ~hasPSUA && ~hasPSUB
                items = {''};
                app.PowerSupplyChannelDropDown.Items = items;
                return;
            end

            items = {};
            if hasPSUA
                items = [items {'A1', 'A2'}];
            end
            if hasPSUB
                items = [items {'B1', 'B2'}];
            end

            currentValue = app.PowerSupplyChannelDropDown.Value;
            
            % Populate power supply dropdown with items
            app.PowerSupplyChannelDropDown.Items = items;
            
            % Make sure there's at least one item and set to first item if current is invalid
            if ~isempty(items)
                if isempty(currentValue) || ~ismember(currentValue, items)
                    app.PowerSupplyChannelDropDown.Value = items{1};
                else
                    app.PowerSupplyChannelDropDown.Value = currentValue;
                end
                % Change the channel if there is a valid selection
                if ~isempty(app.PowerSupplyChannelDropDown.Value)
                    PSUChangeChannel(app, []);
                end
            end
        end

        % Value changed function: SupplyCurrentValueField
        function PSUSetLimitingCurrent(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Current = app.SupplyCurrentValueField.Value;
            end 
            populatePSUChannels(app);        
        end

        % Value changed function: SupplyVoltageStartValueField
        function PSUSetStartVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Start = app.SupplyVoltageStartValueField.Value;
            end   
            populatePSUChannels(app); 
        end

        % Value changed function: SupplyVoltageStopValueField
        function PSUSetStopVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Stop = app.SupplyVoltageStopValueField.Value;
            end 
            populatePSUChannels(app); 
        end

        % Value changed function: SupplyVoltageStepValueField
        function PSUSetStepVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Step = app.SupplyVoltageStepValueField.Value;
            end 
            populatePSUChannels(app);
        end

        % Value changed function: SingleSweepSwitch
        function PSUChangeSingleSweepVoltages(app, event)
            channelFunction = app.SingleSweepSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            % Update GUI based on single PSU value or sweep PSU values.
            if strcmp(channelFunction, 'Single')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'off';
                app.SupplyVoltageStepValueField.Visible = 'off';
            elseif strcmp(channelFunction, 'Sweep')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'on';
                app.SupplyVoltageStepValueField.Visible = 'on';
            end
            
            % Store the mode selection into the channel configuration.
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Mode = channelFunction;
            end
        end

        % Value changed function: DrainGateSwitch
        function PSUChangeDrainGate(app, event)
            channelDesignation = app.DrainGateSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            if strcmp(channelDesignation, 'Drain')
                app.DrainChannels = unique([app.DrainChannels, {selectedChannel}]);
                app.GateChannels = setdiff(app.GateChannels, {selectedChannel});
            elseif strcmp(channelDesignation, 'Gate')
                app.GateChannels = unique([app.GateChannels, {selectedChannel}]);
                app.DrainChannels = setdiff(app.DrainChannels, {selectedChannel});
            end
        end

        % Value changed function: MeasurementTypeDropDown
        function PAChangeFrequencyMeasurementMode(app, event)
            newType = app.MeasurementTypeDropDown.Value;
            
            % The default value of app.TypeFreqMeasurementMode is
            % 'Single Frequency' for safety reasons.
            if isempty(newType)
                app.TypeFreqMeasurementMode = 'Single Frequency';
            else
                app.TypeFreqMeasurementMode = newType;
            end

            if strcmp(app.TypeFreqMeasurementMode, 'Sweep Frequencies')
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.TypeFreqMeasurementMode, 'Single Frequency')
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Value changed function: CalibrationModeDropDown
        function PAChangeCalibrationMode(app, event)
            newMode = app.CalibrationModeDropDown.Value;

            % The default value of app.calibrationMode is 'None' for
            % safety reasons.
            if isempty(newMode)
                app.CalibrationMode = 'None';
            else
                app.CalibrationMode = newMode;
            end

            switch app.CalibrationMode
                case 'None'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';

                    app.InputAttenuationValueField.Value = 0;
                    app.OutputAttenuationValueField.Value = 0;
                case 'Fixed Attenuation'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small Signal'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small + Large Signal'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadInputSmallSignalButton
        function LoadSmallSignalInputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Input S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.InputSpFile = filePath;

                % Display the selected or converted filename in the app.
                app.InputSmallSignalFilenameField.Value = file;

            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadOuputSmallSignalButton
        function LoadSmallSignalOutputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Output S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.OutputSpFile = filePath;
                % Display the selected filename in the app.
                app.OutputSmallSignalFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadLargeSignalDriverResponseButton
        function LoadLargeSignalDriverResponseFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile({'*.xlsx;*.xls;*.csv', 'Data Files (*.xlsx, *.xls, *.csv)'}, 'Select Driver File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % For verification purposes we call readtable().
                driverData = readtable(filePath, 'VariableNamingRule', 'preserve');

                requiredColumns = {'Frequency (MHz)', 'RF Input Power (dBm)', 'Gain'};
                for i = 1:length(requiredColumns)
                    if ~any(strcmp(driverData.Properties.VariableNames, requiredColumns{i}))
                        error('Missing required column: %s', requiredColumns{i});
                    end
                end

                app.DriverFile = filePath;

                app.LargeSignalDriverResponseFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            % Validate the channel configuration first.
            if ~validatePSUChannels(app)
                disp('Validation failed');
                return;
            end

            runPAMeasurement(app);
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            switch app.TypeFreqMeasurementMode
                case 'Single Frequency'
                    app.plotPASingleMeasurement();
                case 'Sweep Frequencies'
                    app.plotPASweepMeasurement();
            end
        end

        % Button pushed function: SavePAData
        function PASaveData(app, event)
            if strcmp(app.TypeFreqMeasurementMode, 'Sweep Frequencies')
                numFreq = length(app.inputFrequencySweep);
                maxLength = max(length(app.inputPdBmSweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.inputFrequencySweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.inputPdBmSweep', app.RFOutputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            elseif strcmp(app.TypeFreqMeasurementMode, 'Single Frequency')
                maxLength = max(length(app.inputPdBmSingle));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.inputFrequencySingle)) = app.inputFrequencySingle;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.inputPdBmSingle', app.RFOutputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                combinedData = loadData('PA');
                if ~isempty(combinedData) 
                    if strcmp(app.TypeFreqMeasurementMode, 'Sweep Frequencies')
                        app.inputFrequencySweep = combinedData.FrequencyHz';
                        app.inputPdBmSweep = combinedData.InputPowerdBm';
                        app.RFOutputPdBm = combinedData.OutputPowerdBm';
                        app.DCDrainPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    elseif strcmp(app.TypeFreqMeasurementMode, 'Single Frequency')
                        app.inputFrequencySingle = unique(combinedData.FrequencyHz)';
                        app.inputPdBmSingle = combinedData.InputPowerdBm';
                        app.RFOutputPdBm = combinedData.OutputPowerdBm';
                        app.DCDrainPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % This function handles the connection of the application to the antenna measurement instruments
            % (Vector Network Analyzer (VNA) and EMCenter). The user selects the appropriate instrument addresses via a dropdown menu, 
            % and the function uses these selections to establish connections. If successful, the instruments are ready for subsequent 
            % commands and data acquisition.      
            
            %instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'}; 
            instrumentNames = {'VNA', 'EMCenter'};
            instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
            
            pattern = '(TCP|USB)[^ ]*';
            
            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstrument(instrumentNames, instrumentResources);
        end

        % Button pushed function: ConnectButton
        function LinearSliderConnect(app, event)
            % This function establishes a connection between the application and the linear slider hardware via TCP/IP. It ensures that 
            % a connection is made only if none exists already, it retrieves the current slider position, and updates the relevant GUI components
            % to reflect the connection status and enable user interactions.

            if isempty(app.LinearSlider)
                try 
                    % Initialize TCP client connection
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';

                    % Get current position from Linear Slider
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = app.LINEAR_SLIDER_RANGE + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;

                    % Enable Linear Slider GUI interface
                    app.AntennaCurrentSpacing.Enable = 'on';
                    app.LinearSliderCurrentPosition.Enable = 'on';
                    app.SpeedPresetDropDown.Enable = 'on';
                    app.MovetoPositionButton.Enable = 'on';
                    app.LinearSliderNewPosition.Enable = 'on';
                catch ME
                    app.displayError(ME);
                end
            end
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderChangeSpeed(app, event)
            % This function updates the movement speed of the linear slider by sending a speed preset command to the hardware. The speed 
            % settings are predefined as presets, with values ranging from 1 (slowest) to 8 (fastest). The selected speed preset is read 
            % from the application's dropdown menu and applied directly to the slider.
            
            try 
                % Send command to linear slider to change its speed
                speedPreset = str2double(app.SpeedPresetDropDown.Value);
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speedPreset));
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderChangePosition(app, event)
            % This function updates the position the linear slider and dynamically adjusts associated parameters based on the slider's movement. 
            % It continuously monitors the slider's status to ensure the desired position is reached and updates the application's calculated 
            % spacing values in real-time.
            
            try
                % Send command to linear slider to move to the specified position
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                
                % Continuously update the setup spacing until motion is complete
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    
                    sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
                    baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
                    totalSpacing = baseSpacing + app.offsetSpacing;
                    
                    % Subtract antenna physical size if provided
                    if ~isempty(app.AntennaPhysicalSize.Value)
                        totalSpacing = totalSpacing - app.AntennaPhysicalSize.Value / 100; % Convert to meters
                    end
                    
                    % Update the displayed spacing
                    app.AntennaCurrentSpacing.Value = totalSpacing;
                    
                    % Optional: add a small delay to prevent overwhelming the system
                    pause(0.1);
                end
        
                app.setupSpacing = app.AntennaCurrentSpacing.Value;
                disp(['Final setup spacing: ', num2str(app.setupSpacing), ' meters']);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopLinearSliderButton
        function LinearSliderStopMovement(app, event)
            % This function sends a stop command to halt the movement of the linear slider. It is triggered 
            % when the user presses the stop button in the linear slider settings menu. 

            try
                writeline(app.LinearSlider, 'AXIS1:STOP')
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: AntennaPhysicalSize
        function LinearSliderApplyAntennaPhysicalOffset(app, event)
            AntennaPhysicalSizeOffset = app.AntennaPhysicalSize.Value;
    
            % Recalculate the current spacing
            sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
            baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
            totalSpacing = baseSpacing + app.offsetSpacing;
            
            % Subtract antenna physical size offset
            if ~isempty(AntennaPhysicalSizeOffset)
                totalSpacing = totalSpacing - AntennaPhysicalSizeOffset / 100; % Convert to meters
            end
            
            % Update the displayed spacing
            app.AntennaCurrentSpacing.Value = totalSpacing;
            
            % Update the setup spacing
            app.setupSpacing = totalSpacing;
        end

        % Value changed function: TableSpeedSlider
        function TableChangeSpeed(app, event)
           % This function updates the turn-table speed value in the application when the user adjusts the TableSpeedSlider. While this 
           % function does not directly update the speed of the hardware, it ensures that the application's internal settings reflect the new speed. The updated value will be used in subsequent operations involving the turntable, allowing the user to adjust the speed without immediate hardware changes.
 
            try
                app.TableSpeedSlider.Value = app.TableSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoAngleButton
        function TableChangeAngle(app, event)
            % This function initiates the process of changing the turn-table angle to a new value. The function sends a command 
            % to set the turn-table's speed and a command to adjust the turn-table's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so.  
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, sprintf('1A:SPEED %d', app.TableSpeedSlider.Value));
                writeline(app.EMCenter, sprintf('1A:SK %d', app.TableNewAngle.Value));
                status = writeread(app.EMCenter,"1A:*OPC?");
                status = str2double(strtrim(status));

                while status ~= 1
                    drawnow;

                    if app.StopRequested
                        break;
                    end

                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTableButton
        function TableStopMovement(app, event)
            % This function is executed when the user requests to stop the turn-table movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the table to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User stopped turn-table movement.');
            
            try
                writeline(app.EMCenter, '1A:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MoveTowertoAngleButton
        function TowerChangeAngle(app, event)
            % This function initiates the process of changing the tower's angle to a new value. The function sends a command 
            % to set the tower's speed to a default value of 20 and a command to adjust the tower's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so. 
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, '1B:SPEED 20');
                writeline(app.EMCenter, sprintf('1B:SK %d', app.TowerNewAngle.Value));
            
                while true                    
                    drawnow;
                    
                    if app.StopRequested
                        break;
                    end

                    pause(0.1);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTowerButton
        function TowerStopMovement(app, event)
            % This function is executed when the user requests to stop the tower movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the tower to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User stopped tower movement.');

            try
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            % This function validates the user inputs for the VNA measurement settings and performs the selected measurement 
            % type (either "Gain Characterization" or "Pattern Measurement"). It ensures that the start and end frequencies are set and distinct, 
            % the number of sweep points is specified, and the antenna physical size is provided. Based on the chosen measurement type, the 
            % function either calls the getBoresightGain method for gain characterization or the get2DAntennaGain method for 2D antenna pattern measurement. 
            
            try
                if isempty(app.VNAStartFrequency.Value) || isempty(app.VNAEndFrequency.Value) ||(app.VNAStartFrequency.Value == app.VNAEndFrequency.Value) 
                    error('Invalid frequency values, start and end frequencies must be set and different.');
                elseif isempty(app.VNASweepPoints.Value)
                    error('Number of sweep points not set.');
                elseif isempty(app.AntennaPhysicalSize.Value)
                    error('Antenna physical dimension offset not set');
                end
            
                startFrequency = app.VNAStartFrequency.Value * 1E6;
                endFrequency = app.VNAEndFrequency.Value * 1E6;
                sweepPoints = app.VNASweepPoints.Value;
            
                switch app.VNAMeasurement.Value
                    case 'Gain Characterization'
                        app.getBoresightGain(startFrequency, endFrequency, sweepPoints);
                    case 'Pattern Measurement'
                        app.get2DAntennaGain(startFrequency, endFrequency, sweepPoints);
                end
           
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTestButton
        function AntennaStopMeasurement(app, event)
            % This function handles the user's request to stop the ongoing 2D radiation pattern measurement. When the stop request is triggered,
            % the function terminates the measurement process, checks if any data has been recorded, and prompts the user to decide whether to save 
            % the collected data. If the user opts to save, the function prepares the data and saves it. 

            app.StopRequested = true;
            disp('User stopped the test. Terminating measurement and saving data if necessary.');
            
            try
                writeline(app.EMCenter, '1A:ST');
                pause(1);

                % Check if any data has been recorded thus far.
                if ~isempty(app.AzimuthAngles) && any(app.AzimuthAngles)
                    % Identify valid data points since the arrays were
                    % initialized with 0's.
                    validIndices = app.TestFrequencies > 0;
                
                    % Filter data arrays
                    filteredAzimuthAngles = app.AzimuthAngles(validIndices);
                    filteredFrequencies = app.TestFrequencies(validIndices);
                    filteredAntennaGain = app.AntennaGain_dBi(validIndices);
                    filteredReturnLoss = app.S11(validIndices);
                
                    % Combine filtered data
                    combinedData = [double(filteredAzimuthAngles)', double(filteredFrequencies)', double(filteredAntennaGain)', double(filteredReturnLoss)'];
                
                    % Handle the case where no valid data exists
                    if isempty(combinedData)
                        disp('No valid data was recorded. Skipping save operation.');
                        return;
                    end
                
                    % Ask the user if they want to save the data
                    question = 'Do you want to save collected data?';
                    choice = uiconfirm(app.UIFigure, question, 'Stop Test', 'Options', {'Yes', 'No'}, 'DefaultOption', 1);
                
                    if strcmp(choice, 'Yes')
                        % Prepare data for the saveData function
                        combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                        passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
                        saveData(combinedData, combinedNames, passedLimit);
                    end
                end
                
                % Reset antenna measurement properties for efficient data
                % handling.
                app.resetAntennaMeasurementProperties();
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            % This function is responsible for plotting the 2D antenna gain measurement results, providing 
            % the user with visual insights into the measurement data. 

            sweepPts = length(app.sweepFrequencies);
            app.plotAntenna2DMeasurement(sweepPts);
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            % This function is responsible for loading an antenna gain file into the application. 
            % The file should adhere to the application's predefined column headers for antenna gain data:
            %
            %       Azimuth Angles (deg)
            %       Frequency (Hz)
            %       Gain (dBi) (Gain in dBi)
            %       Return Loss (dB) 
            % 
            % If any fields are missing, an error is raised to inform the user. Upon successful loading, 
            % the data is extracted and used to populate relevant fields within the application, 
            % including dropdown menus for selecting specific angles and frequencies for plotting.

            try 
                % Load the antenna gain file
                combinedAntennaData = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(combinedAntennaData))
                    return;
                end

                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(combinedAntennaData, 'AzimuthAnglesdeg')
                    missingFields{end+1} = 'Azimuth Angles (deg)';
                end
                if ~isfield(combinedAntennaData, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(combinedAntennaData, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The antenna gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % Extract data from the file and load it into the
                % application if all required fields are found.
                app.azimuthAngles = unique(combinedAntennaData.AzimuthAnglesdeg)';
                app.sweepFrequencies = unique(combinedAntennaData.FrequencyHz)';
                app.antennaGain = combinedAntennaData.GaindBi';
                app.antennaRL = combinedAntennaData.ReturnLossdB';

                % Populate dropdown menus, with the angles and frequencies
                % from the gain file so user can select which specific
                % angle or frequency to plot.
                app.PlotAngleDropDown.Items = string(app.azimuthAngles);
                app.PlotFrequencyGHzDropDown.Items = string(app.sweepFrequencies/1E9);

                % Set default values for the angle and frequency.
                app.selectedAngle = app.PlotAngleDropDown.Items(1);
                app.selectedFrequency = app.PlotFrequencyGHzDropDown.Items(1);

                % Set default values for the angle and frequency ranges, so
                % the plots only display the necessary data. 
                app.angleRange = 0;
                app.frequencyRange = 1;
            catch ME    
                app.displayError(ME);
            end
        end

        % Value changed function: PlotAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            % This function automatically adjusts the angle range for plotting based on the user's selected angle from the dropdown menu.
            % It retrieves the selected angle and then calculates the corresponding index in the list of available angles.
            % The angle range is updated to reflect the index of the selected angle, which is used to determine which data to plot.

            app.selectedAngle = app.PlotAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlotAngleDropDown.Items, app.selectedAngle)) - 1;
        end

        % Value changed function: PlotFrequencyGHzDropDown
        function AntennaSelectedFrequencyValue(app, event)
            % This function automatically adjusts the frequency range for plotting based on the user's selected frequency from the dropdown menu.
            % It retrieves the selected frequency and calculates the corresponding index in the list of available frequencies. 
            % The frequency range is updated to reflect the index of the selected frequency, which is used to determine which data to plot.

            app.selectedFrequency = app.PlotFrequencyGHzDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlotFrequencyGHzDropDown.Items, app.selectedFrequency));
        end

        % Button pushed function: BrowseReferenceFileButton
        function LoadReferenceGainFile(app, event)
            % The reference gain file must adhere to the application's specified column headers format:
            %
            %       Frequency (Hz)
            %       Gain (dBi)
            %       Return Loss (dB) 
            % 
            % The function verifies that all required fields are present in the file, and if any are missing, it raises an error. 
            % If the file is valid, the path to the loaded reference gain file is displayed to the user.

            try 
                % Load the reference gain file
                app.ReferenceGainFile = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(app.ReferenceGainFile))
                    return;
                end
            
                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(app.ReferenceGainFile, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(app.ReferenceGainFile, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(app.ReferenceGainFile, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The reference gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % If the file presents no errors, the file path is loaded
                % into the file, and displayed to the user.
                app.ReferenceGainFileField.Value = evalin('base', 'loadedFilePath');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotReferenceGainFile(app, event)
            % This function plots the reference gain data that has been loaded into the application. It extracts the frequency, 
            % antenna gain, and return loss from the reference gain file and displays them on the corresponding plots for the user.

            try
                % Clear current plots.
                cla(app.GainvsFrequencyBoresightGain);
                cla(app.ReturnLossBoresightGain);

                % Extract antenna measurement parameters from the file.
                Frequencies = app.ReferenceGainFile.FrequencyHz / 1E9;
                AntennaGain =  app.ReferenceGainFile.GaindBi;
                ReturnLoss =  app.ReferenceGainFile.ReturnLossdB;

                % Plot the parameters onto the plots.
                plot(app.GainvsFrequencyBoresightGain, Frequencies, AntennaGain);
                plot(app.ReturnLossBoresightGain, Frequencies, ReturnLoss);

                % Improves the plot appeareance, line thickness can be
                % modified.
                improveAxesAppearance(app.GainvsFrequencyBoresightGain, 'LineThickness', 2);
                improveAxesAppearance(app.ReturnLossBoresightGain, 'LineThickness', 2);
            catch ME
                app.displayError(ME);
            end
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function handles the app's closure behavior. When the user attempts to close the app, the function prompts them 
            % with a confirmation dialog. If the user confirms the closure, the function will clean up by closing all active instrument 
            % connections. If the user cancels the closure, the function does nothing and returns to the app.

            InstrumentConnections = {app.PowerSupplyA, app.PowerSupplyB, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = 'Close ARES App?';
            choice = uiconfirm(app.UIFigure, question, 'Confirm Close', 'Options', {'Yes', 'No'});

            switch choice
                case 'Yes'
                    for i = 1:numel(InstrumentConnections)
                        if ~isempty(InstrumentConnections{i})
                            flush(InstrumentConnections{i});
                            InstrumentConnections{i} = [];
                        end
                    end
                    app.delete
                case 'No'
            end
        end

        % Button down function: InstrumentsTab
        function InstrumentsTabButtonDown(app, event)
            
        end

        % Button down function: PASettingsPanel
        function PASettingsPanelButtonDown(app, event)
            %disp('not lol')
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            %disp('lol')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.GridLayout19 = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout19);
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.GridLayout20 = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.GridLayout20);
            app.Image.ImageSource = 'ARES logo.jpg';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = 'Automated Radio Evaluation Suite';
            app.Label_2 = uilabel(app.GridLayout20);
            app.Label_2.Text = '';
            app.V09BETARelaseLabel = uilabel(app.GridLayout20);
            app.V09BETARelaseLabel.Text = 'V0.9 BETA Relase';
            app.Hyperlink = uihyperlink(app.GridLayout20);
            app.Hyperlink.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Hyperlink.Text = 'GitHub Repo';
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel = uilabel(app.GridLayout20);
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.Hyperlink2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.Hyperlink2.Text = 'PA Measurement Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.Hyperlink2_2.Text = 'Antenna Tutorial';
            app.Hyperlink2_3 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_3.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.Hyperlink2_3.Text = 'README';
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.GridLayout28 = uigridlayout(app.PowerAmplifierTab);
            app.ResultsTabs = uitabgroup(app.GridLayout28);
            app.SingleTab = uitab(app.ResultsTabs);
            app.SingleTab.Title = 'Single';
            app.GridLayout16 = uigridlayout(app.SingleTab);
            app.SingleFrequencyPAPlot = uiaxes(app.GridLayout16);
            title(app.SingleFrequencyPAPlot, 'PA Performance Metrics')
            xlabel(app.SingleFrequencyPAPlot, 'Output Power (dBm)')
            zlabel(app.SingleFrequencyPAPlot, 'Z')
            app.SweepTab = uitab(app.ResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout2 = uigridlayout(app.SweepTab);
            app.GainvsOutputPowerPlot = uiaxes(app.GridLayout2);
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power')
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)')
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)')
            zlabel(app.GainvsOutputPowerPlot, 'Z')
            app.PeakGainPlot = uiaxes(app.GridLayout2);
            title(app.PeakGainPlot, 'Peak Gain')
            xlabel(app.PeakGainPlot, 'Frequency (GHz)')
            ylabel(app.PeakGainPlot, 'Gain (dB)')
            zlabel(app.PeakGainPlot, 'Z')
            app.PeakDEPAEPlot = uiaxes(app.GridLayout2);
            title(app.PeakDEPAEPlot, 'Peak DE and PAE')
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)')
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)')
            zlabel(app.PeakDEPAEPlot, 'Z')
            app.CompressionPointsPlot = uiaxes(app.GridLayout2);
            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points')
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)')
            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)')
            zlabel(app.CompressionPointsPlot, 'Z')
            app.TestSettingsPanel = uipanel(app.GridLayout28);
            app.TestSettingsPanel.Title = 'Test Settings';
            app.GridLayout12 = uigridlayout(app.TestSettingsPanel);
            app.StartPAMeasurement = uibutton(app.GridLayout12, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.GridLayout12, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.SavePAData = uibutton(app.GridLayout12, 'push');
            app.SavePAData.ButtonPushedFcn = createCallbackFcn(app, @PASaveData, true);
            app.SavePAData.Text = 'Save Test';
            app.PlotPAData = uibutton(app.GridLayout12, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.PASettingsPanel = uitabgroup(app.GridLayout28);
            app.PASettingsPanel.ButtonDownFcn = createCallbackFcn(app, @PASettingsPanelButtonDown, true);
            app.InstrumentsTab = uitab(app.PASettingsPanel);
            app.InstrumentsTab.Title = 'Instruments';
            app.InstrumentsTab.ButtonDownFcn = createCallbackFcn(app, @InstrumentsTabButtonDown, true);
            app.GridLayout27 = uigridlayout(app.InstrumentsTab);
            app.PowerSupplyAStatusLabel = uilabel(app.GridLayout27);
            app.PowerSupplyAStatusLabel.Text = 'Status:';
            app.PowerSupplyADropDown = uidropdown(app.GridLayout27);
            app.PowerSupplyADropDown.Items = {''};
            app.PowerSupplyADropDown.Editable = 'on';
            app.PowerSupplyADropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyADropDown.Placeholder = 'Enter address:';
            app.PowerSupplyADropDown.Value = '';
            app.PowerSupplyBStatusLabel = uilabel(app.GridLayout27);
            app.PowerSupplyBStatusLabel.Text = 'Status:';
            app.PowerSupplyBDropDown = uidropdown(app.GridLayout27);
            app.PowerSupplyBDropDown.Items = {};
            app.PowerSupplyBDropDown.Editable = 'on';
            app.PowerSupplyBDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyBDropDown.Placeholder = 'Enter address:';
            app.PowerSupplyBDropDown.Value = {};
            app.SignalGeneratorStatusLabel = uilabel(app.GridLayout27);
            app.SignalGeneratorStatusLabel.Text = 'Status:';
            app.SignalGeneratorDropDown = uidropdown(app.GridLayout27);
            app.SignalGeneratorDropDown.Items = {};
            app.SignalGeneratorDropDown.Editable = 'on';
            app.SignalGeneratorDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SignalGeneratorDropDown.Placeholder = 'Enter address:';
            app.SignalGeneratorDropDown.Value = {};
            app.SpectrumAnalyzerStatusLabel = uilabel(app.GridLayout27);
            app.SpectrumAnalyzerStatusLabel.Text = 'Status:';
            app.PowerSupplyALabel = uilabel(app.GridLayout27);
            app.PowerSupplyALabel.Text = 'Power Supply A';
            app.PowerSupplyBLabel = uilabel(app.GridLayout27);
            app.PowerSupplyBLabel.Text = 'Power Supply B';
            app.SignalGeneratorLabel = uilabel(app.GridLayout27);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.SpectrumAnalyzerDropDown = uidropdown(app.GridLayout27);
            app.SpectrumAnalyzerDropDown.Items = {};
            app.SpectrumAnalyzerDropDown.Editable = 'on';
            app.SpectrumAnalyzerDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SpectrumAnalyzerDropDown.Placeholder = 'Enter address:';
            app.SpectrumAnalyzerDropDown.Value = {};
            app.PAMeasurementDelayValueField = uieditfield(app.GridLayout27, 'numeric');
            app.PAMeasurementDelayValueField.Limits = [0 Inf];
            app.PAMeasurementDelayValueField.Value = 0.1;
            app.PAConnectButton = uibutton(app.GridLayout27, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.SpectrumAnalyzerLabel = uilabel(app.GridLayout27);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.MeasurementDelaysEditFieldLabel = uilabel(app.GridLayout27);
            app.MeasurementDelaysEditFieldLabel.Text = 'Measurement Delay (s)';
            app.PADisconnectButton = uibutton(app.GridLayout27, 'push');
            app.PADisconnectButton.Text = 'Disconnect';
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout29 = uigridlayout(app.PowerSupplyTab);
            app.PowerSupplyModeDropDown = uidropdown(app.GridLayout29);
            app.PowerSupplyModeDropDown.Items = {'No Supply', 'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.PowerSupplyModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeMode, true);
            app.PowerSupplyModeDropDown.Value = 'Single Supply';
            app.PowerSupplyChannelDropDown = uidropdown(app.GridLayout29);
            app.PowerSupplyChannelDropDown.Items = {''};
            app.PowerSupplyChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PSUChannelDropDownOpening, true);
            app.PowerSupplyChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeChannel, true);
            app.PowerSupplyChannelDropDown.Value = '';
            app.DrainGateSwitch = uiswitch(app.GridLayout29, 'slider');
            app.DrainGateSwitch.Items = {'Drain', 'Gate'};
            app.DrainGateSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeDrainGate, true);
            app.DrainGateSwitch.Value = 'Drain';
            app.SingleSweepSwitch = uiswitch(app.GridLayout29, 'slider');
            app.SingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.SingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeSingleSweepVoltages, true);
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyCurrentValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetLimitingCurrent, true);
            app.LimitingCurrentALabel = uilabel(app.GridLayout29);
            app.LimitingCurrentALabel.Text = 'Current Limit (A)';
            app.SupplyVoltageStartNameField = uilabel(app.GridLayout29);
            app.SupplyVoltageStartNameField.Text = 'Start Voltage (V)';
            app.SupplyVoltageStartValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStartValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStartVoltage, true);
            app.SupplyVoltageStopEditField = uilabel(app.GridLayout29);
            app.SupplyVoltageStopEditField.Text = 'Stop Voltage (V)';
            app.SupplyVoltageStopValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStopValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStopVoltage, true);
            app.SupplyVoltageStepNameField = uilabel(app.GridLayout29);
            app.SupplyVoltageStepNameField.Text = 'Step Voltage (V)';
            app.SupplyVoltageStepValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStepValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStepVoltage, true);
            app.SupplyModeLabel = uilabel(app.GridLayout29);
            app.SupplyModeLabel.Text = 'Supply Mode';
            app.SupplyChannelDropDownLabel = uilabel(app.GridLayout29);
            app.SupplyChannelDropDownLabel.Text = 'Supply Channel';
            app.SignalAnalysisTab = uitab(app.PASettingsPanel);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.GridLayout24 = uigridlayout(app.SignalAnalysisTab);
            app.MeasurementTypeDropDown = uidropdown(app.GridLayout24);
            app.MeasurementTypeDropDown.Items = {'Single Frequency', 'Sweep Frequencies'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeFrequencyMeasurementMode, true);
            app.MeasurementTypeDropDown.Value = 'Single Frequency';
            app.StartFrequencyMHzLabel = uilabel(app.GridLayout24);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.StartFrequency = uieditfield(app.GridLayout24, 'numeric');
            app.EndFrequencyMHzLabel = uilabel(app.GridLayout24);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.EndFrequency = uieditfield(app.GridLayout24, 'numeric');
            app.FrequencyStepMHzLabel = uilabel(app.GridLayout24);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.FrequencyStep = uieditfield(app.GridLayout24, 'numeric');
            app.StartPowerdBmEditFieldLabel = uilabel(app.GridLayout24);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.StartPower = uieditfield(app.GridLayout24, 'numeric');
            app.EndPowerdBmEditFieldLabel = uilabel(app.GridLayout24);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.EndPower = uieditfield(app.GridLayout24, 'numeric');
            app.PowerStepdBmEditField = uilabel(app.GridLayout24);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.PowerStep = uieditfield(app.GridLayout24, 'numeric');
            app.SpanNameField = uilabel(app.GridLayout24);
            app.SpanNameField.Text = 'Span (MHz)';
            app.SpanValueField = uieditfield(app.GridLayout24, 'numeric');
            app.SpanValueField.Value = 40;
            app.SweepPointsNameField = uilabel(app.GridLayout24);
            app.SweepPointsNameField.Text = 'Sweep Points';
            app.SweepPointsValueField = uieditfield(app.GridLayout24, 'numeric');
            app.SweepPointsValueField.Limits = [0 Inf];
            app.SweepPointsValueField.Value = 1001;
            app.ReferenceLevelNameField = uilabel(app.GridLayout24);
            app.ReferenceLevelNameField.Text = 'Reference Level (dB)';
            app.ReferenceLevelValueField = uieditfield(app.GridLayout24, 'numeric');
            app.MeasurementTypeDropDownLabel = uilabel(app.GridLayout24);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.SignalGeneratorSettingsLabel = uilabel(app.GridLayout24);
            app.SignalGeneratorSettingsLabel.Text = 'Signal Generator Settings';
            app.SpectrumAnalyzerSettingsLabel = uilabel(app.GridLayout24);
            app.SpectrumAnalyzerSettingsLabel.Text = 'Spectrum Analyzer Settings';
            app.CalibrationTab = uitab(app.PASettingsPanel);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout30 = uigridlayout(app.CalibrationTab);
            app.CalibrationModeDropDown = uidropdown(app.GridLayout30);
            app.CalibrationModeDropDown.Items = {'None', 'Fixed Attenuation', 'Small Signal', 'Small + Large Signal'};
            app.CalibrationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeCalibrationMode, true);
            app.CalibrationModeDropDown.Value = 'None';
            app.InputAttenuationValueField = uieditfield(app.GridLayout30, 'numeric');
            app.OutputAttenuationValueField = uieditfield(app.GridLayout30, 'numeric');
            app.LoadInputSmallSignalButton = uibutton(app.GridLayout30, 'push');
            app.LoadInputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalInputS2PFile, true);
            app.LoadInputSmallSignalButton.Text = 'Load Input S-Parameters';
            app.InputSmallSignalFilenameField = uieditfield(app.GridLayout30, 'text');
            app.InputSmallSignalFilenameField.Editable = 'off';
            app.InputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadOuputSmallSignalButton = uibutton(app.GridLayout30, 'push');
            app.LoadOuputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalOutputS2PFile, true);
            app.LoadOuputSmallSignalButton.Text = 'Load Output S-Parameters';
            app.CalibrationModeLabel = uilabel(app.GridLayout30);
            app.CalibrationModeLabel.Text = 'Calibration Mode';
            app.InputAttenuationdBLabel = uilabel(app.GridLayout30);
            app.InputAttenuationdBLabel.Text = 'Input Attenuation (dB)';
            app.OutputSmallSignalFilenameField = uieditfield(app.GridLayout30, 'text');
            app.OutputSmallSignalFilenameField.Editable = 'off';
            app.OutputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadLargeSignalDriverResponseButton = uibutton(app.GridLayout30, 'push');
            app.LoadLargeSignalDriverResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLargeSignalDriverResponseFile, true);
            app.LoadLargeSignalDriverResponseButton.Text = 'Load Large-Signal Driver Response';
            app.LargeSignalDriverResponseFilenameField = uieditfield(app.GridLayout30, 'text');
            app.LargeSignalDriverResponseFilenameField.Editable = 'off';
            app.LargeSignalDriverResponseFilenameField.Placeholder = '.xlsx,.csv';
            app.OutputAttenuationdBEditFieldLabel = uilabel(app.GridLayout30);
            app.OutputAttenuationdBEditFieldLabel.Text = 'Output Attenuation (dB)';
            app.SmallSignalFrequencyResponseLabel = uilabel(app.GridLayout30);
            app.SmallSignalFrequencyResponseLabel.Text = 'Small-Signal Frequency Response';
            app.DriverResponseLabel = uilabel(app.GridLayout30);
            app.DriverResponseLabel.Text = 'Driver Response';
            app.FixedCalibrationLabel = uilabel(app.GridLayout30);
            app.FixedCalibrationLabel.Text = 'Fixed Calibration';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.GridLayout36 = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.GridLayout36);
            app.AntennaGainTab = uitab(app.AntennaPattern);
            app.AntennaGainTab.Title = 'Antenna Gain';
            app.GridLayout3_2 = uigridlayout(app.AntennaGainTab);
            app.ReturnLossBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.ReturnLossBoresightGain, 'Return Loss vs. Frequency')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.GainvsFrequencyBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.PlotReferenceGainButton = uibutton(app.GridLayout3_2, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReferenceGainFile, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.ReferenceGainFileField = uieditfield(app.GridLayout3_2, 'text');
            app.ReferenceGainFileField.Editable = 'off';
            app.BrowseReferenceFileButton = uibutton(app.GridLayout3_2, 'push');
            app.BrowseReferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceGainFile, true);
            app.BrowseReferenceFileButton.Text = 'Browse Reference File';
            app.RadiationPatternTab = uitab(app.AntennaPattern);
            app.RadiationPatternTab.Title = 'Radiation Pattern';
            app.GridLayout3 = uigridlayout(app.RadiationPatternTab);
            app.ReturnLoss2D = uiaxes(app.GridLayout3);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.GridLayout3);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.GridLayout3);
            title(app.GainvsAngle2D, 'Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.AntennaSettings = uitabgroup(app.GridLayout36);
            app.InstrumentsTab_2 = uitab(app.AntennaSettings);
            app.InstrumentsTab_2.Title = 'Instruments';
            app.GridLayout31 = uigridlayout(app.InstrumentsTab_2);
            app.VNALabel = uilabel(app.GridLayout31);
            app.VNALabel.Text = 'VNA';
            app.VNAStatusLabel = uilabel(app.GridLayout31);
            app.VNAStatusLabel.Text = 'Status:';
            app.VNADropDown = uidropdown(app.GridLayout31);
            app.VNADropDown.Items = {};
            app.VNADropDown.Editable = 'on';
            app.VNADropDown.Placeholder = 'Enter address:';
            app.VNADropDown.Value = {};
            app.EMCenterDropDown = uidropdown(app.GridLayout31);
            app.EMCenterDropDown.Items = {};
            app.EMCenterDropDown.Editable = 'on';
            app.EMCenterDropDown.Placeholder = 'Enter address:';
            app.EMCenterDropDown.Value = {};
            app.EMCenterLabel = uilabel(app.GridLayout31);
            app.EMCenterLabel.Text = 'EMCenter';
            app.EMCenterStatusLabel = uilabel(app.GridLayout31);
            app.EMCenterStatusLabel.Text = 'Status:';
            app.AntennaConnectButton = uibutton(app.GridLayout31, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.AntennaDisconnectButton = uibutton(app.GridLayout31, 'push');
            app.AntennaDisconnectButton.Text = 'Disconnect';
            app.MeasurementDelaysLabel = uilabel(app.GridLayout31);
            app.MeasurementDelaysLabel.Text = 'Measurement Delay (s)';
            app.AntennaMeasurementDelayValueField = uieditfield(app.GridLayout31, 'numeric');
            app.AntennaMeasurementDelayValueField.Value = 0.1;
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.GridLayout32 = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.GridLayout32);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency';
            app.VNAStartFrequency = uieditfield(app.GridLayout32, 'numeric');
            app.VNAStartFrequency.Limits = [0 Inf];
            app.VNAStartFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAStartFrequency.AllowEmpty = 'on';
            app.VNAStartFrequency.Value = [];
            app.EndFrequencyMHzLabel_6 = uilabel(app.GridLayout32);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency';
            app.VNAEndFrequency = uieditfield(app.GridLayout32, 'numeric');
            app.VNAEndFrequency.Limits = [0 Inf];
            app.VNAEndFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAEndFrequency.AllowEmpty = 'on';
            app.VNAEndFrequency.Value = [];
            app.VNAMeasurement = uidropdown(app.GridLayout32);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.MeasurementDropDown_2Label = uilabel(app.GridLayout32);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.VNASweepPoints = uieditfield(app.GridLayout32, 'numeric');
            app.VNASweepPoints.Limits = [0 Inf];
            app.VNASweepPoints.AllowEmpty = 'on';
            app.VNASweepPoints.Value = [];
            app.SweepPointsLabel = uilabel(app.GridLayout32);
            app.SweepPointsLabel.Text = 'Sweep Points';
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.GridLayout33 = uigridlayout(app.Table);
            app.StopTableButton = uibutton(app.GridLayout33, 'push');
            app.StopTableButton.ButtonPushedFcn = createCallbackFcn(app, @TableStopMovement, true);
            app.StopTableButton.Text = 'Stop Table';
            app.TableNewAngle = uieditfield(app.GridLayout33, 'numeric');
            app.TableNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MovetoAngleButton = uibutton(app.GridLayout33, 'push');
            app.MovetoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TableChangeAngle, true);
            app.MovetoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.GridLayout33);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle ';
            app.TableStartAngle = uieditfield(app.GridLayout33, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.TableStartAngle.AllowEmpty = 'on';
            app.TableStartAngle.Placeholder = '(deg)';
            app.TableStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.GridLayout33);
            app.StartFrequectSpanMHzLabel_12.Text = 'Angle Step Size ';
            app.TableStepAngle = uieditfield(app.GridLayout33, 'numeric');
            app.TableStepAngle.AllowEmpty = 'on';
            app.TableStepAngle.Placeholder = '(deg)';
            app.TableStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.GridLayout33);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle ';
            app.TableEndAngle = uieditfield(app.GridLayout33, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.TableEndAngle.AllowEmpty = 'on';
            app.TableEndAngle.Placeholder = '(deg)';
            app.TableEndAngle.Value = [];
            app.TableSpeedSliderLabel = uilabel(app.GridLayout33);
            app.TableSpeedSliderLabel.Text = 'Table Speed';
            app.TableSpeedSlider = uislider(app.GridLayout33);
            app.TableSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TableChangeSpeed, true);
            app.TableSpeedSlider.Value = 50;
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.GridLayout34 = uigridlayout(app.TowerTab);
            app.StopTowerButton = uibutton(app.GridLayout34, 'push');
            app.StopTowerButton.ButtonPushedFcn = createCallbackFcn(app, @TowerStopMovement, true);
            app.StopTowerButton.Text = 'Stop Tower';
            app.TowerNewAngle = uieditfield(app.GridLayout34, 'numeric');
            app.TowerNewAngle.Limits = [-180 180];
            app.TowerNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MoveTowertoAngleButton = uibutton(app.GridLayout34, 'push');
            app.MoveTowertoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TowerChangeAngle, true);
            app.MoveTowertoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.GridLayout34);
            app.StartFrequectSpanMHzLabel_16.Enable = 'off';
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle ';
            app.TowerStartAngle = uieditfield(app.GridLayout34, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.TowerStartAngle.AllowEmpty = 'on';
            app.TowerStartAngle.Enable = 'off';
            app.TowerStartAngle.Placeholder = '(deg)';
            app.TowerStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.GridLayout34);
            app.StartFrequectSpanMHzLabel_17.Enable = 'off';
            app.StartFrequectSpanMHzLabel_17.Text = 'Angle Step Size ';
            app.TowerStepAngle = uieditfield(app.GridLayout34, 'numeric');
            app.TowerStepAngle.AllowEmpty = 'on';
            app.TowerStepAngle.Enable = 'off';
            app.TowerStepAngle.Placeholder = '(deg)';
            app.TowerStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.GridLayout34);
            app.StartFrequectSpanMHzLabel_18.Enable = 'off';
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle ';
            app.TowerEndAngle = uieditfield(app.GridLayout34, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.TowerEndAngle.AllowEmpty = 'on';
            app.TowerEndAngle.Enable = 'off';
            app.TowerEndAngle.Placeholder = '(deg)';
            app.TowerEndAngle.Value = [];
            app.TowerSpeedSliderLabel = uilabel(app.GridLayout34);
            app.TowerSpeedSliderLabel.Text = 'Tower Speed';
            app.TowerSpeedSlider = uislider(app.GridLayout34);
            app.TowerSpeedSlider.Value = 50;
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.GridLayout35 = uigridlayout(app.LinearSliderTab);
            app.StopLinearSliderButton = uibutton(app.GridLayout35, 'push');
            app.StopLinearSliderButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderStopMovement, true);
            app.StopLinearSliderButton.Text = 'Stop Linear Slider';
            app.MovetoPositionButton = uibutton(app.GridLayout35, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderChangePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.GridLayout35, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.ConnectButton = uibutton(app.GridLayout35, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.ConnectButton.Text = 'Connect';
            app.SpeedPresetDropDownLabel = uilabel(app.GridLayout35);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.GridLayout35);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderChangeSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.CurrentSpacingLabel = uilabel(app.GridLayout35);
            app.CurrentSpacingLabel.Text = 'Current Spacing ';
            app.AntennaCurrentSpacing = uieditfield(app.GridLayout35, 'numeric');
            app.AntennaCurrentSpacing.ValueDisplayFormat = '%11.4g (m)';
            app.AntennaCurrentSpacing.AllowEmpty = 'on';
            app.AntennaCurrentSpacing.Editable = 'off';
            app.AntennaCurrentSpacing.Value = [];
            app.SliderPositioncmLabel = uilabel(app.GridLayout35);
            app.SliderPositioncmLabel.Text = 'Slider Current Position';
            app.LinearSliderCurrentPosition = uieditfield(app.GridLayout35, 'numeric');
            app.LinearSliderCurrentPosition.ValueDisplayFormat = '%11.4g (cm)';
            app.LinearSliderCurrentPosition.AllowEmpty = 'on';
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.LinearSliderCurrentPosition.Value = [];
            app.AntennaOffsetLabel = uilabel(app.GridLayout35);
            app.AntennaOffsetLabel.Text = 'Antenna Offset ';
            app.AntennaPhysicalSize = uieditfield(app.GridLayout35, 'numeric');
            app.AntennaPhysicalSize.Limits = [0 Inf];
            app.AntennaPhysicalSize.ValueDisplayFormat = '%11.4g (cm)';
            app.AntennaPhysicalSize.AllowEmpty = 'on';
            app.AntennaPhysicalSize.ValueChangedFcn = createCallbackFcn(app, @LinearSliderApplyAntennaPhysicalOffset, true);
            app.AntennaPhysicalSize.Value = [];
            app.TestandPlotSettingsPanel = uipanel(app.GridLayout36);
            app.TestandPlotSettingsPanel.Title = 'Test and Plot Settings';
            app.GridLayout4 = uigridlayout(app.TestandPlotSettingsPanel);
            app.PlotFrequencyGHzDropDownLabel = uilabel(app.GridLayout4);
            app.PlotFrequencyGHzDropDownLabel.Text = 'Plot Frequency (GHz)';
            app.PlotFrequencyGHzDropDown = uidropdown(app.GridLayout4);
            app.PlotFrequencyGHzDropDown.Items = {''};
            app.PlotFrequencyGHzDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlotFrequencyGHzDropDown.Value = '';
            app.PlottingAngleLabel = uilabel(app.GridLayout4);
            app.PlottingAngleLabel.Text = 'Plot Angle (°)';
            app.PlotAngleDropDown = uidropdown(app.GridLayout4);
            app.PlotAngleDropDown.Items = {''};
            app.PlotAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlotAngleDropDown.Value = '';
            app.StartTestButton = uibutton(app.GridLayout4, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.PlotResultsButton = uibutton(app.GridLayout4, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.LoadResultsButton = uibutton(app.GridLayout4, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.StopTestButton = uibutton(app.GridLayout4, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStopMeasurement, true);
            app.StopTestButton.Text = 'Stop Test';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.PASettingsPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end